classdef Home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HomeMenu                        matlab.ui.container.Menu
        EnterDataMenu                   matlab.ui.container.Menu
        EditDataMenu                    matlab.ui.container.Menu
        EditStandardVoltageReadingMenu  matlab.ui.container.Menu
        DisplayGraphMenu                matlab.ui.container.Menu
        DataAnalysisMenu                matlab.ui.container.Menu
        CalculatorMenu                  matlab.ui.container.Menu
        CalculatorPanel                 matlab.ui.container.Panel
        DopantSourceDropDown            matlab.ui.control.DropDown
        DopantSourceDropDownLabel       matlab.ui.control.Label
        DiffusionLengthEditField        matlab.ui.control.NumericEditField
        DiffusionLengthEditFieldLabel   matlab.ui.control.Label
        TimeminEditField                matlab.ui.control.NumericEditField
        TimeminEditFieldLabel           matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        TemperatureCLabel               matlab.ui.control.Label
        DataAnalysisPanel               matlab.ui.container.Panel
        DisplayGraphPanel               matlab.ui.container.Panel
        EditDataPanel                   matlab.ui.container.Panel
        EnterStandardVoltageReadingPanel  matlab.ui.container.Panel
        EnterDataPanel                  matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        EditDataButton                  matlab.ui.control.Button
        CalculatorButton                matlab.ui.control.Button
        EditStandardVoltageReadingButton_2  matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DataAnalysisButton              matlab.ui.control.Button
        EnterDataButton                 matlab.ui.control.Button
        DisplayGraphButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        calculatorsourcedl % source chosen in the calculator function for diffusion length
    end
    
    methods (Access = private)
        
         function [dif]=diffusionlength(temp,time,sourcet)
            if sourcet<3
                D0=0.76;
                Ea=3.46;
            else
                D0=3.85;
                Ea=3.66;
            end
            kb=8.617*10^(-5);
            ctk=273.15;
            D=D0*exp(-Ea/(kb*(temp+ctk)));
            dif=2*sqrt(D*time*60)*10^(4);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %app.UIFigure.WindowState = 'maximized';
          app.UIFigure.KeyPressFcn = @(src, event) ...
          strcmp(event.Key, 'escape') && close(app.UIFigure);
          
        end

        % Button pushed function: EnterDataButton
        function Enterpress(app, event)
          %figpos=app.UIFigure.Position;
          %app.EnterDataPanel.Position=[0 0 figpos(3) figpos(4)];
          app.EnterDataPanel.Visible = 'on';             
        end

        % Button pushed function: EditDataButton
        function editdatapress(app, event)
            app.EditDataPanel.Visible='On';
        end

        % Button pushed function: EditStandardVoltageReadingButton_2
        function EDVRpress(app, event)
           app.EnterStandardVoltageReadingPanel.Visible='On';
        end

        % Button pushed function: DisplayGraphButton
        function DGpress(app, event)
            app.DisplayGraphPanel.Visible='On';
        end

        % Button pushed function: DataAnalysisButton
        function DApress(app, event)
            app.DataAnalysisPanel.Visible='On';
        end

        % Button pushed function: CalculatorButton
        function Calculatorpress(app, event)
            app.CalculatorPanel.Visible='On';
        end

        % Button pushed function: CloseButton
        function closepress(app, event)
            close(app.UIFigure)
        end

        % Menu selected function: HomeMenu
        function Homebutton(app, event)
            % Button pushed function: CloseAllButton
              % Get all properties of the app
              props = properties(app);
    
             % Loop through all properties
             for i = 1:numel(props)
                 val = app.(props{i});
        
                  % Check if the property is a Panel (uipanel)
                  if isa(val, 'matlab.ui.container.Panel')
                        val.Visible = 'off';
                  end
             end

                % Optional: also hide overlays if you created any
                if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
                  delete(app.OverlayAxes);
                end


        end

        % Menu selected function: EnterDataMenu
        function EnterDatamenu(app, event)
                        % Button pushed function:Enter Data Menu
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.EnterDataPanel.Visible='On';
        end

        % Menu selected function: EditStandardVoltageReadingMenu
        function EnterStandardVoltageReadingMenu(app, event)
                        % Button pushed function: Enter Standard Voltage
                        % Reading
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.EnterStandardVoltageReadingPanel.Visible='On';
        end

        % Menu selected function: EditDataMenu
        function editdatamenupress(app, event)
                                    % Button pushed function: Edit Data 
                        % Reading
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.EditDataPanel.Visible='On';
        end

        % Menu selected function: DisplayGraphMenu
        function Displaygraphpress(app, event)
                                    % Button pushed function: Display Graph
                        % Reading
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.DisplayGraphPanel.Visible='On';
        end

        % Menu selected function: DataAnalysisMenu
        function DataAnalysisMenupress(app, event)
                                    % Button pushed function: Data Analysis
                        % Reading
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.DataAnalysisPanel.Visible='On';
        end

        % Menu selected function: CalculatorMenu
        function calculatormenupress(app, event)
                                    % Button pushed function: Calculator
                        % Reading
    % Get all properties of the app
    props = properties(app);
    
    % Loop through all properties
    for i = 1:numel(props)
        val = app.(props{i});
        
        % Check if the property is a Panel (uipanel)
        if isa(val, 'matlab.ui.container.Panel')
            val.Visible = 'off';
        end
    end

    % Optional: also hide overlays if you created any
    if isprop(app, 'OverlayAxes') && isvalid(app.OverlayAxes)
        delete(app.OverlayAxes);
    end

    app.CalculatorPanel.Visible='On';
        end

        % Value changed function: DopantSourceDropDown
        function dopantsourcecalcdl(app, event)
            value = app.DopantSourceDropDown.Value;
            app.DopantSourceDropDown.Items = {'Choose One', 'TP - 250', 'TP - 470', 'GS - 139', 'GS - 245'};

            switch value
                case 'Chose One'
                    app.calculatorsourcedl=0;
                case 'TP - 250'
                    app.calculatorsourcedl=3;
                case 'TP - 470'
                    app.calculatorsourcedl=4;
                case 'GS - 139'
                    app.calculatorsourcedl=2;
                case 'GS - 245'
                    app.calculatorsourcedl=1;
            end
            app.DiffusionLengthEditField.Value=diffusionlength(app.TemperatureCEditField.Value,app.TimeminEditField.Value,app.calculatorsourcedl);
        end

        % Value changed function: TemperatureCEditField
        function temperatureCCalc(app, event)
            app.DiffusionLengthEditField.Value=diffusionlength(app.TemperatureCEditField.Value,app.TimeminEditField.Value,app.calculatorsourcedl);
        end

        % Value changed function: TimeminEditField
        function timemincalc(app, event)
            app.DiffusionLengthEditField.Value=diffusionlength(app.TemperatureCEditField.Value,app.TimeminEditField.Value,app.calculatorsourcedl);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HomeMenu = uimenu(app.Menu);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @Homebutton, true);
            app.HomeMenu.Text = 'Home';
            app.EnterDataMenu = uimenu(app.Menu);
            app.EnterDataMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterDatamenu, true);
            app.EnterDataMenu.Text = 'Enter Data';
            app.EditDataMenu = uimenu(app.Menu);
            app.EditDataMenu.MenuSelectedFcn = createCallbackFcn(app, @editdatamenupress, true);
            app.EditDataMenu.Text = 'Edit Data';
            app.EditStandardVoltageReadingMenu = uimenu(app.Menu);
            app.EditStandardVoltageReadingMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterStandardVoltageReadingMenu, true);
            app.EditStandardVoltageReadingMenu.Text = 'Edit Standard Voltage Reading';
            app.DisplayGraphMenu = uimenu(app.Menu);
            app.DisplayGraphMenu.MenuSelectedFcn = createCallbackFcn(app, @Displaygraphpress, true);
            app.DisplayGraphMenu.Text = 'Display Graph';
            app.DataAnalysisMenu = uimenu(app.Menu);
            app.DataAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @DataAnalysisMenupress, true);
            app.DataAnalysisMenu.Text = 'Data Analysis';
            app.CalculatorMenu = uimenu(app.Menu);
            app.CalculatorMenu.MenuSelectedFcn = createCallbackFcn(app, @calculatormenupress, true);
            app.CalculatorMenu.Text = 'Calculator';
            app.DisplayGraphButton = uibutton(app.UIFigure, 'push');
            app.DisplayGraphButton.ButtonPushedFcn = createCallbackFcn(app, @DGpress, true);
            app.DisplayGraphButton.Text = 'Display Graph';
            app.EnterDataButton = uibutton(app.UIFigure, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @Enterpress, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.DataAnalysisButton = uibutton(app.UIFigure, 'push');
            app.DataAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DApress, true);
            app.DataAnalysisButton.Text = 'Data Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMG_3314.jpg';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closepress, true);
            app.CloseButton.Text = 'Close';
            app.EditStandardVoltageReadingButton_2 = uibutton(app.UIFigure, 'push');
            app.EditStandardVoltageReadingButton_2.ButtonPushedFcn = createCallbackFcn(app, @EDVRpress, true);
            app.EditStandardVoltageReadingButton_2.Text = 'Edit Standard Voltage Reading';
            app.CalculatorButton = uibutton(app.UIFigure, 'push');
            app.CalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @Calculatorpress, true);
            app.CalculatorButton.Text = 'Calculator';
            app.EditDataButton = uibutton(app.UIFigure, 'push');
            app.EditDataButton.ButtonPushedFcn = createCallbackFcn(app, @editdatapress, true);
            app.EditDataButton.Text = 'Edit Data';
            app.EnterDataPanel = uipanel(app.UIFigure);
            app.EnterDataPanel.Title = 'Enter Data';
            app.EnterDataPanel.Visible = 'off';
            app.DropDownLabel = uilabel(app.EnterDataPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.EnterDataPanel);
            app.EnterStandardVoltageReadingPanel = uipanel(app.UIFigure);
            app.EnterStandardVoltageReadingPanel.Title = 'Enter Standard Voltage Reading';
            app.EnterStandardVoltageReadingPanel.Visible = 'off';
            app.EditDataPanel = uipanel(app.UIFigure);
            app.EditDataPanel.Title = 'Edit Data';
            app.EditDataPanel.Visible = 'off';
            app.DisplayGraphPanel = uipanel(app.UIFigure);
            app.DisplayGraphPanel.Title = 'Display Graph';
            app.DisplayGraphPanel.Visible = 'off';
            app.DataAnalysisPanel = uipanel(app.UIFigure);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.DataAnalysisPanel.Visible = 'off';
            app.CalculatorPanel = uipanel(app.UIFigure);
            app.CalculatorPanel.Title = 'Calculator';
            app.TemperatureCLabel = uilabel(app.CalculatorPanel);
            app.TemperatureCLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.CalculatorPanel, 'numeric');
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @temperatureCCalc, true);
            app.TemperatureCEditField.Tag = 'TemperautreCedit';
            app.TimeminEditFieldLabel = uilabel(app.CalculatorPanel);
            app.TimeminEditFieldLabel.Text = 'Time (min)';
            app.TimeminEditField = uieditfield(app.CalculatorPanel, 'numeric');
            app.TimeminEditField.ValueChangedFcn = createCallbackFcn(app, @timemincalc, true);
            app.TimeminEditField.Tag = 'TimeminEdit';
            app.DiffusionLengthEditFieldLabel = uilabel(app.CalculatorPanel);
            app.DiffusionLengthEditFieldLabel.Text = 'Diffusion Length';
            app.DiffusionLengthEditField = uieditfield(app.CalculatorPanel, 'numeric');
            app.DopantSourceDropDownLabel = uilabel(app.CalculatorPanel);
            app.DopantSourceDropDownLabel.Text = 'Dopant Source';
            app.DopantSourceDropDown = uidropdown(app.CalculatorPanel);
            app.DopantSourceDropDown.Items = {'Choose One', 'TP - 250', 'TP - 470', 'GS - 139', 'GS - 245'};
            app.DopantSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @dopantsourcecalcdl, true);
            app.DopantSourceDropDown.Value = 'Choose One';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Home

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end